classdef RegressionGaussianBasisFcns < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        ReadyLampLabel                  matlab.ui.control.Label
        ReadyLamp                       matlab.ui.control.Lamp
        WeightsLabel                    matlab.ui.control.Label
        ComputeWeightsPanel             matlab.ui.container.Panel
        ComputerErrorLabel              matlab.ui.control.Label
        ComputerErrorEditField          matlab.ui.control.NumericEditField
        ShowComputedResultsSwitchLabel  matlab.ui.control.Label
        ShowComputedResultsSwitch       matlab.ui.control.Switch
        ComputeWeightsButton            matlab.ui.control.Button
        ComputedWeightsEditFieldLabel   matlab.ui.control.Label
        ComputedWeightsEditField        matlab.ui.control.EditField
        TextArea_3                      matlab.ui.control.TextArea
        GenerateGoalFunctionPanel       matlab.ui.container.Panel
        YourErrorLabel                  matlab.ui.control.Label
        YourErrorEditField              matlab.ui.control.NumericEditField
        NoiseSwitchLabel                matlab.ui.control.Label
        NoiseSwitch                     matlab.ui.control.ToggleSwitch
        ShowGoalFunctionSwitchLabel     matlab.ui.control.Label
        ShowGoalFunctionSwitch          matlab.ui.control.Switch
        GenerateGoalFunctionButton      matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        ResetWeightsButton              matlab.ui.control.Button
        w1SliderLabel                   matlab.ui.control.Label
        w1Slider                        matlab.ui.control.Slider
        w2SliderLabel                   matlab.ui.control.Label
        w2Slider                        matlab.ui.control.Slider
        w3SliderLabel                   matlab.ui.control.Label
        w3Slider                        matlab.ui.control.Slider
        w4SliderLabel                   matlab.ui.control.Label
        w4Slider                        matlab.ui.control.Slider
        w5SliderLabel                   matlab.ui.control.Label
        w5Slider                        matlab.ui.control.Slider
        toEditFieldLabel                matlab.ui.control.Label
        toEditField                     matlab.ui.control.NumericEditField
        TextArea_2                      matlab.ui.control.TextArea
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = private)
        weights = ones(5,1); % Weightvector for regression
        BasisFcns % Gaussian Basis Function Matrix
        RandomWeights = zeros(5,1); % Random Weights for goal function
        GoalFcn = zeros(100,1); % Goal Function
        NoiseVector = zeros(100,1); % Gaussian Noise for Goal Function
        Error = 0; % Regression Error
        ComputedWeights = zeros(5,1); % Weights computed with ridge regression
    end
    
    
    methods (Access = private)
        
        function UpdatePlot(app)
            app.UIAxes.NextPlot = 'replaceall'
            bf = plot(app.UIAxes, app.weights' .* app.BasisFcns);
            for i = 1:5; bf(i).Color(4) = 0.5; end
            app.UIAxes.NextPlot = 'add'
            plot(app.UIAxes, app.BasisFcns * app.weights, 'LineWidth', 1.5, 'Color', [0.2 0.8 0.8 0.85])
            
            if strcmpi(app.ShowGoalFunctionSwitch.Value, 'On') && strcmpi(app.ShowComputedResultsSwitch.Value, 'Off')
                plot(app.UIAxes, app.GoalFcn, 'LineWidth', 2.8, 'Color', [0.8 0.2 0.2 0.5])
                legend(app.UIAxes, 'BasisFcn 1', 'BasisFcn 2', 'BasisFcn 3', 'BasisFcn 4', 'BasisFcn 5', 'Result','GoalFcn',...
                    'Location', 'eastoutside', "Orientation",...
                    "vertical", 'FontSize', 5.1)
                
                app.YourErrorEditField.Value = ComputeError(app, app.weights);
                app.ComputerErrorEditField.Value = 0;
                
            elseif strcmpi(app.ShowComputedResultsSwitch.Value, 'On') && strcmpi(app.ShowGoalFunctionSwitch.Value, 'Off')
                plot(app.UIAxes, app.BasisFcns * app.ComputedWeights, 'LineWidth', 0.9, 'Color', [0.4 0.4 0.4 1]) %, 'LineStyle', '--'
                legend(app.UIAxes, 'BasisFcn 1', 'BasisFcn 2', 'BasisFcn 3', 'BasisFcn 4', 'BasisFcn 5', 'Result', 'Comp. Result',...
                    'Location', 'eastoutside', "Orientation",...
                    "vertical", 'FontSize', 5.1)
                
                app.ComputerErrorEditField.Value = ComputeError(app, app.ComputedWeights);
                app.YourErrorEditField.Value = 0;     
                
            elseif strcmpi(app.ShowGoalFunctionSwitch.Value, 'On') && strcmpi(app.ShowComputedResultsSwitch.Value, 'On')
                plot(app.UIAxes, app.GoalFcn, 'LineWidth', 2.8, 'Color', [0.8 0.2 0.2 0.5])               
                plot(app.UIAxes, app.BasisFcns * app.ComputedWeights, 'LineWidth', 0.9, 'Color', [0.4 0.4 0.4 1]) %, 'LineStyle', '--'
                legend(app.UIAxes, 'BasisFcn 1', 'BasisFcn 2', 'BasisFcn 3', 'BasisFcn 4', 'BasisFcn 5', 'Result','GoalFcn', 'Comp. Result',...
                    'Location', 'eastoutside', "Orientation",...
                    "vertical", 'FontSize', 5.1)
                
                app.ComputerErrorEditField.Value = ComputeError(app, app.ComputedWeights);
                app.YourErrorEditField.Value = ComputeError(app, app.weights);
  
                
            else
                legend(app.UIAxes, 'BasisFcn 1', 'BasisFcn 2', 'BasisFcn 3', 'BasisFcn 4', 'BasisFcn 5', 'Result',...
                    'Location', 'eastoutside', "Orientation",...
                    "vertical", 'FontSize', 5.1)
                
                app.ComputerErrorEditField.Value = 0;                
                app.YourErrorEditField.Value = 0;
            end
            title(app.UIAxes, 'Linear Regression with Gaussian Basis Functions');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');

        end
        
        function GenerateBasisFcnMatrix(app)            
            %Creates normalized, evenly-spaced Gaussian Basis Functions in matrix form. 
            N = 5;
            T = 100;
            h = 0.5*(T/(N-1))^2;
            %% Gaussian Basis function as anonymous function
            gbf = @(n,t) exp(-((t-((n-1)*T/(N-1)))^2 /(2*h))); %h is std_dev not variance in this case
            %% Form feature vector
            gbf_matrix = zeros(T, N);
            for n = 1:N
                for t=1:T
                    gbf_matrix(t,n) = gbf(n,t); %generate the gbf's and store them individually in the rows of the feature matrix
                end
            end
            %% Normalize the GBF's
            app.BasisFcns = gbf_matrix ./ sum(gbf_matrix,2);
            app.ReadyLamp.Color = 'green';
        end
        
        function Error = ComputeError(app, WeightVector)
            Error = sqrt(sum((app.GoalFcn - (app.BasisFcns * WeightVector)).^2));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             drawnow;
%             app.UIFigure.WindowState = 'maximized';    
            GenerateBasisFcnMatrix(app);
            UpdatePlot(app);
        end

        % Button pushed function: GenerateGoalFunctionButton
        function GenerateGoalFunctionButtonPushed(app, event)
            app.RandomWeights = -10 + (10+10) * rand(5,1);
            app.GoalFcn = app.BasisFcns * app.RandomWeights;
            NoiseSwitchValueChanged(app, event)
            UpdatePlot(app);
        end

        % Button pushed function: ResetWeightsButton
        function ResetWeightsButtonPushed(app, event)
            resetValue = app.toEditField.Value;
            app.weights = resetValue * ones(5,1);
            app.w1Slider.Value = resetValue;
            app.w2Slider.Value = resetValue;
            app.w3Slider.Value = resetValue;
            app.w4Slider.Value = resetValue;
            app.w5Slider.Value = resetValue;
            UpdatePlot(app);
        end

        % Value changed function: ShowGoalFunctionSwitch
        function ShowGoalFunctionSwitchValueChanged(app, event)
            UpdatePlot(app);            
        end

        % Value changed function: NoiseSwitch
        function NoiseSwitchValueChanged(app, event)
            if strcmpi(app.NoiseSwitch.Value, 'On')
                app.NoiseVector = normrnd(0, 0.1, 100, 1);
                app.GoalFcn = app.GoalFcn + app.NoiseVector;
            else
                app.GoalFcn = app.BasisFcns * app.RandomWeights;
            end
            UpdatePlot(app);
        end

        % Button pushed function: ComputeWeightsButton
        function ComputeWeightsButtonPushed(app, event)
            app.ComputedWeights = (app.BasisFcns'*app.BasisFcns + 1e-12*eye(5)) \ (app.BasisFcns' * app.GoalFcn);
            app.ComputedWeightsEditField.Value = num2str(app.ComputedWeights','% .1f');
            UpdatePlot(app)
        end

        % Value changed function: ShowComputedResultsSwitch
        function ShowComputedResultsSwitchValueChanged(app, event)
            UpdatePlot(app);            
        end

        % Value changed function: w1Slider
        function w1SliderValueChanged(app, event)
            app.weights(1) = app.w1Slider.Value;
            UpdatePlot(app);
        end

        % Value changed function: w2Slider
        function w2SliderValueChanged(app, event)
            app.weights(2) = app.w2Slider.Value;
            UpdatePlot(app);
        end

        % Value changed function: w3Slider
        function w3SliderValueChanged(app, event)
            app.weights(3) = app.w3Slider.Value;
            UpdatePlot(app);
        end

        % Value changed function: w4Slider
        function w4SliderValueChanged(app, event)
            app.weights(4) = app.w4Slider.Value;
            UpdatePlot(app);
        end

        % Value changed function: w5Slider
        function w5SliderValueChanged(app, event)
            app.weights(5) = app.w5Slider.Value;
            UpdatePlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Linear Regression with Gaussian Basis Functions')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ReadyLampLabel = uilabel(app.UIFigure);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.UIFigure);
            app.WeightsLabel = uilabel(app.UIFigure);
            app.WeightsLabel.Text = 'Weights:';
            app.ComputeWeightsPanel = uipanel(app.UIFigure);
            app.ComputeWeightsPanel.Title = 'Compute Weights';
            app.ComputerErrorLabel = uilabel(app.ComputeWeightsPanel);
            app.ComputerErrorLabel.Text = 'Computer Error:';
            app.ComputerErrorEditField = uieditfield(app.ComputeWeightsPanel, 'numeric');
            app.ComputerErrorEditField.Editable = 'off';
            app.ShowComputedResultsSwitchLabel = uilabel(app.ComputeWeightsPanel);
            app.ShowComputedResultsSwitchLabel.Text = 'Show Computed Results';
            app.ShowComputedResultsSwitch = uiswitch(app.ComputeWeightsPanel, 'slider');
            app.ShowComputedResultsSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowComputedResultsSwitchValueChanged, true);
            app.ComputeWeightsButton = uibutton(app.ComputeWeightsPanel, 'push');
            app.ComputeWeightsButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeWeightsButtonPushed, true);
            app.ComputeWeightsButton.Text = 'Compute Weights';
            app.ComputedWeightsEditFieldLabel = uilabel(app.ComputeWeightsPanel);
            app.ComputedWeightsEditFieldLabel.Text = 'Computed Weights';
            app.ComputedWeightsEditField = uieditfield(app.ComputeWeightsPanel, 'text');
            app.TextArea_3 = uitextarea(app.ComputeWeightsPanel);
            app.TextArea_3.Value = {'3) Compute the weights to match the Goal Function by taking the (pseudo-) inverse of the Basis Function Matrix .'};
            app.GenerateGoalFunctionPanel = uipanel(app.UIFigure);
            app.GenerateGoalFunctionPanel.Title = 'Generate Goal Function';
            app.YourErrorLabel = uilabel(app.GenerateGoalFunctionPanel);
            app.YourErrorLabel.Text = 'Your Error:';
            app.YourErrorEditField = uieditfield(app.GenerateGoalFunctionPanel, 'numeric');
            app.YourErrorEditField.Editable = 'off';
            app.NoiseSwitchLabel = uilabel(app.GenerateGoalFunctionPanel);
            app.NoiseSwitchLabel.Text = 'Noise';
            app.NoiseSwitch = uiswitch(app.GenerateGoalFunctionPanel, 'toggle');
            app.NoiseSwitch.ValueChangedFcn = createCallbackFcn(app, @NoiseSwitchValueChanged, true);
            app.ShowGoalFunctionSwitchLabel = uilabel(app.GenerateGoalFunctionPanel);
            app.ShowGoalFunctionSwitchLabel.Text = 'Show Goal Function';
            app.ShowGoalFunctionSwitch = uiswitch(app.GenerateGoalFunctionPanel, 'slider');
            app.ShowGoalFunctionSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowGoalFunctionSwitchValueChanged, true);
            app.GenerateGoalFunctionButton = uibutton(app.GenerateGoalFunctionPanel, 'push');
            app.GenerateGoalFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateGoalFunctionButtonPushed, true);
            app.GenerateGoalFunctionButton.Text = 'Generate Goal Function';
            app.TextArea = uitextarea(app.GenerateGoalFunctionPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'2) Generate a Goal Function and try to adjust the weights manually to match the goal function as good as possible. The better your approximation, the lower is "Your Error''.'};
            app.ResetWeightsButton = uibutton(app.UIFigure, 'push');
            app.ResetWeightsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetWeightsButtonPushed, true);
            app.ResetWeightsButton.Text = 'Reset Weights';
            app.w1SliderLabel = uilabel(app.UIFigure);
            app.w1SliderLabel.Text = 'w1';
            app.w1Slider = uislider(app.UIFigure);
            app.w1Slider.Limits = [-10 10];
            app.w1Slider.ValueChangedFcn = createCallbackFcn(app, @w1SliderValueChanged, true);
            app.w2SliderLabel = uilabel(app.UIFigure);
            app.w2SliderLabel.Text = 'w2';
            app.w2Slider = uislider(app.UIFigure);
            app.w2Slider.Limits = [-10 10];
            app.w2Slider.ValueChangedFcn = createCallbackFcn(app, @w2SliderValueChanged, true);
            app.w3SliderLabel = uilabel(app.UIFigure);
            app.w3SliderLabel.Text = 'w3';
            app.w3Slider = uislider(app.UIFigure);
            app.w3Slider.Limits = [-10 10];
            app.w3Slider.ValueChangedFcn = createCallbackFcn(app, @w3SliderValueChanged, true);
            app.w4SliderLabel = uilabel(app.UIFigure);
            app.w4SliderLabel.Text = 'w4';
            app.w4Slider = uislider(app.UIFigure);
            app.w4Slider.Limits = [-10 10];
            app.w4Slider.ValueChangedFcn = createCallbackFcn(app, @w4SliderValueChanged, true);
            app.w5SliderLabel = uilabel(app.UIFigure);
            app.w5SliderLabel.Text = 'w5';
            app.w5Slider = uislider(app.UIFigure);
            app.w5Slider.Limits = [-10 10];
            app.w5Slider.ValueChangedFcn = createCallbackFcn(app, @w5SliderValueChanged, true);
            app.toEditFieldLabel = uilabel(app.UIFigure);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.UIFigure, 'numeric');
            app.toEditField.Value = 1;
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'1) Adjust the Weights of the Basis Functions manually and observe how the resulting function changes.'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Math_img.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RegressionGaussianBasisFcns

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end